classdef Piano < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        recordTab           matlab.ui.container.Tab
        currentNote         matlab.ui.control.Label
        field_label         matlab.ui.control.Label
        SaveNotesButton     matlab.ui.control.Button
        text_field          matlab.ui.control.EditField
        SaveButton          matlab.ui.control.Button
        ResetButton         matlab.ui.control.Button
        playTab             matlab.ui.container.Tab
        SpeedSliderLabel_6  matlab.ui.control.Label
        SpeedSliderLabel_5  matlab.ui.control.Label
        SpeedSlider         matlab.ui.control.Slider
        SpeedSliderLabel_4  matlab.ui.control.Label
        playButton          matlab.ui.control.Button
        ListBox             matlab.ui.control.ListBox
        mysongsLabel        matlab.ui.control.Label
        song                matlab.ui.control.Label
        ButtonGroup         matlab.ui.container.ButtonGroup
        A4_d_Button         matlab.ui.control.ToggleButton
        B4_Button           matlab.ui.control.ToggleButton
        F3_d_Button         matlab.ui.control.ToggleButton
        G3_d_Button         matlab.ui.control.ToggleButton
        F3_Button           matlab.ui.control.ToggleButton
        G3_Button           matlab.ui.control.ToggleButton
        A4_Button           matlab.ui.control.ToggleButton
        C6_Button           matlab.ui.control.ToggleButton
        A6_d_Button         matlab.ui.control.ToggleButton
        B6_Button           matlab.ui.control.ToggleButton
        C5_d_Button         matlab.ui.control.ToggleButton
        D5_d_Button         matlab.ui.control.ToggleButton
        F5_d_Button         matlab.ui.control.ToggleButton
        G5_d_Button         matlab.ui.control.ToggleButton
        C5_Button           matlab.ui.control.ToggleButton
        D5_Button           matlab.ui.control.ToggleButton
        E5_Button           matlab.ui.control.ToggleButton
        F5_Button           matlab.ui.control.ToggleButton
        G5_Button           matlab.ui.control.ToggleButton
        A6_Button           matlab.ui.control.ToggleButton
        tmp_2               matlab.ui.control.ToggleButton
        A5_d_Button         matlab.ui.control.ToggleButton
        B5_Button           matlab.ui.control.ToggleButton
        C4_d_Button         matlab.ui.control.ToggleButton
        D4_d_Button         matlab.ui.control.ToggleButton
        F4_d_Button         matlab.ui.control.ToggleButton
        G4_d_Button         matlab.ui.control.ToggleButton
        C4_Button           matlab.ui.control.ToggleButton
        D4_Button           matlab.ui.control.ToggleButton
        E4_Button           matlab.ui.control.ToggleButton
        F4_Button           matlab.ui.control.ToggleButton
        G4_Button           matlab.ui.control.ToggleButton
        A5_Button           matlab.ui.control.ToggleButton
        tmp                 matlab.ui.control.ToggleButton
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            samplefreq=48000;
            time=1;
               
            originalColor = get(app.ButtonGroup.SelectedObject,'BackgroundColor'); % Get original color
            currentNote=event.NewValue.Text; %save text from button
            [octave,notenum] = octave_note(currentNote); %return octave number ant note number in the octave
            set(app.currentNote,"Text",currentNote);%write note to label
            
            set(app.song,"Text",[app.song.Text currentNote ',']);%add note to sequence of notes

            %color the button
            event.NewValue.BackgroundColor = buttonColor(notenum);

            %play sound
            soundNote(octave,notenum,samplefreq,time);

            % wait before reset button color
            pause(0.2);

            % Re-enable the button and reset its color
            set(event.NewValue,'BackgroundColor',originalColor);
            set(app.ButtonGroup.SelectedObject,"Value",0);

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             % Clear labels
             app.song.Text = '';
             app.currentNote.Text = '';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        import matlab.io.*;
        
        %save name of file and song to new excel file
        filename = strcat(app.text_field.Value, '.xlsx');
        songtmp = split(app.song.Text, ','); %split the contents of the label into an array
        %all col song start with'song'
        data = cat(1, 'song', songtmp);
        xlswrite(filename, data);
            
        %enable and disable buttons
            app.song.Text = '';
            set(app.text_field,"Value","")
            set(app.field_label,"Visible",0)
            set(app.field_label,"Visible",0)
            set(app.text_field,"Visible",0)
            set(app.SaveButton,"Enable",0)
            set(app.SaveButton,"Visible",0)
            set(app.ResetButton,"Enable",1)
            set(app.ResetButton,"Visible",1) 
            set(app.SaveNotesButton,"Enable",1)
            set(app.SaveNotesButton,"Visible",1)
            set(app.ButtonGroup,"Enable",1)
            set(app.tmp,"Value",1)
        end

        % Button pushed function: SaveNotesButton
        function SaveNotesButtonPushed(app, event)
        %if string of notes exist enable and disable buttons
            if (app.song.Text ~= "")
            set(app.ButtonGroup,"Enable",0)
            app.currentNote.Text = '';
            set(app.ResetButton,"Enable",0)
            set(app.ResetButton,"Visible",0) 
            set(app.SaveNotesButton,"Enable",0)
            set(app.SaveNotesButton,"Visible",0)
            set(app.text_field,"Enable",1)
            set(app.field_label,"Visible",1)
            set(app.text_field,"Visible",1)
            set(app.SaveButton,"Enable",1)
            set(app.SaveButton,"Visible",1)
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            %by select play, initialize all excel file names into listbox
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title, 'play')
                set(app.ButtonGroup,"Enable",0)
                files = dir('*.xlsx');
                excel_files = {files.name};
                excel_files_without_ext = cellfun(@(x) x(1:end-5), excel_files, 'UniformOutput', false);
                app.ListBox.Items = excel_files_without_ext;
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            %enable and disable buttons
            set(app.ButtonGroup,"Enable",1)
            set(app.playButton,"Enable",1)
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            set(app.ButtonGroup,"Enable",1)
            samplefreq=48000;
            %read file
            value = app.ListBox.Value;
            import matlab.io.*;
            filename = strcat(value, '.xlsx');
            songtmp=readtable(filename);
            %play file
            for i=1:height(songtmp)
                currentNote = string(songtmp{i,1});
                button = findobj(app.ButtonGroup.Children, 'Text', currentNote);
                originalColor = button.BackgroundColor;
                [octave,notenum] = octave_note(currentNote);            

                %color the button
                button.BackgroundColor = buttonColor(notenum);

                %play sound
                time=app.SpeedSlider.Value;
                soundNote(octave,notenum,samplefreq,time);
                pause(0.3);
                % Re-enable the button and reset its color
                button.BackgroundColor = originalColor;
                set(app.ButtonGroup.SelectedObject,"Value",0);
                set(app.tmp,"Value",1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.tmp = uitogglebutton(app.ButtonGroup);
            app.tmp.Text = 'Black';
            app.tmp.Value = true;
            app.A5_Button = uitogglebutton(app.ButtonGroup);
            app.A5_Button.Text = 'A5';
            app.G4_Button = uitogglebutton(app.ButtonGroup);
            app.G4_Button.Text = 'G4';
            app.F4_Button = uitogglebutton(app.ButtonGroup);
            app.F4_Button.Text = 'F4';
            app.E4_Button = uitogglebutton(app.ButtonGroup);
            app.E4_Button.Text = 'E4';
            app.D4_Button = uitogglebutton(app.ButtonGroup);
            app.D4_Button.Text = 'D4';
            app.C4_Button = uitogglebutton(app.ButtonGroup);
            app.C4_Button.Text = 'C4';
            app.G4_d_Button = uitogglebutton(app.ButtonGroup);
            app.G4_d_Button.Text = 'G#4';
            app.F4_d_Button = uitogglebutton(app.ButtonGroup);
            app.F4_d_Button.Text = 'F#4';
            app.D4_d_Button = uitogglebutton(app.ButtonGroup);
            app.D4_d_Button.Text = 'D#4';
            app.C4_d_Button = uitogglebutton(app.ButtonGroup);
            app.C4_d_Button.Text = 'C#4';
            app.B5_Button = uitogglebutton(app.ButtonGroup);
            app.B5_Button.Text = 'B5';
            app.A5_d_Button = uitogglebutton(app.ButtonGroup);
            app.A5_d_Button.Text = 'A#5';
            app.tmp_2 = uitogglebutton(app.ButtonGroup);
            app.tmp_2.Enable = 'off';
            app.tmp_2.Text = 'Black';
            app.A6_Button = uitogglebutton(app.ButtonGroup);
            app.A6_Button.Text = 'A6';
            app.G5_Button = uitogglebutton(app.ButtonGroup);
            app.G5_Button.Text = 'G5';
            app.F5_Button = uitogglebutton(app.ButtonGroup);
            app.F5_Button.Text = 'F5';
            app.E5_Button = uitogglebutton(app.ButtonGroup);
            app.E5_Button.Text = 'E5';
            app.D5_Button = uitogglebutton(app.ButtonGroup);
            app.D5_Button.Text = 'D5';
            app.C5_Button = uitogglebutton(app.ButtonGroup);
            app.C5_Button.Text = 'C5';
            app.G5_d_Button = uitogglebutton(app.ButtonGroup);
            app.G5_d_Button.Text = 'G#5';
            app.F5_d_Button = uitogglebutton(app.ButtonGroup);
            app.F5_d_Button.Text = 'F#5';
            app.D5_d_Button = uitogglebutton(app.ButtonGroup);
            app.D5_d_Button.Text = 'D#5';
            app.C5_d_Button = uitogglebutton(app.ButtonGroup);
            app.C5_d_Button.Text = 'C#5';
            app.B6_Button = uitogglebutton(app.ButtonGroup);
            app.B6_Button.Text = 'B6';
            app.A6_d_Button = uitogglebutton(app.ButtonGroup);
            app.A6_d_Button.Text = 'A#6';
            app.C6_Button = uitogglebutton(app.ButtonGroup);
            app.C6_Button.Text = 'C6';
            app.A4_Button = uitogglebutton(app.ButtonGroup);
            app.A4_Button.Text = 'A4';
            app.G3_Button = uitogglebutton(app.ButtonGroup);
            app.G3_Button.Text = 'G3';
            app.F3_Button = uitogglebutton(app.ButtonGroup);
            app.F3_Button.Text = 'F3';
            app.G3_d_Button = uitogglebutton(app.ButtonGroup);
            app.G3_d_Button.Text = 'G#3';
            app.F3_d_Button = uitogglebutton(app.ButtonGroup);
            app.F3_d_Button.Text = 'F#3';
            app.B4_Button = uitogglebutton(app.ButtonGroup);
            app.B4_Button.Text = 'B4';
            app.A4_d_Button = uitogglebutton(app.ButtonGroup);
            app.A4_d_Button.Text = 'A#4';
            app.song = uilabel(app.UIFigure);
            app.song.Visible = 'off';
            app.song.Text = '';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.recordTab = uitab(app.TabGroup);
            app.recordTab.Title = 'record';
            app.ResetButton = uibutton(app.recordTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.recordTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.text_field = uieditfield(app.recordTab, 'text');
            app.text_field.Enable = 'off';
            app.text_field.Visible = 'off';
            app.SaveNotesButton = uibutton(app.recordTab, 'push');
            app.SaveNotesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNotesButtonPushed, true);
            app.SaveNotesButton.Text = 'Save';
            app.field_label = uilabel(app.recordTab);
            app.field_label.Visible = 'off';
            app.field_label.Text = 'write your song name';
            app.currentNote = uilabel(app.recordTab);
            app.currentNote.Text = '';
            app.playTab = uitab(app.TabGroup);
            app.playTab.Title = 'play';
            app.mysongsLabel = uilabel(app.playTab);
            app.mysongsLabel.Text = 'my songs:';
            app.ListBox = uilistbox(app.playTab);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.playButton = uibutton(app.playTab, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Enable = 'off';
            app.playButton.Text = 'Play';
            app.SpeedSliderLabel_4 = uilabel(app.playTab);
            app.SpeedSliderLabel_4.Text = 'Speed';
            app.SpeedSlider = uislider(app.playTab);
            app.SpeedSlider.Limits = [0.3 3];
            app.SpeedSlider.MajorTicks = [0.3 1 1.5 2 2.5 3];
            app.SpeedSlider.Value = 1;
            app.SpeedSliderLabel_5 = uilabel(app.playTab);
            app.SpeedSliderLabel_5.Text = 'Fast';
            app.SpeedSliderLabel_6 = uilabel(app.playTab);
            app.SpeedSliderLabel_6.Text = 'Slow';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Piano

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end